classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout1             matlab.ui.container.GridLayout
        Panel2                  matlab.ui.container.Panel
        GridLayout3             matlab.ui.container.GridLayout
        Label_2                 matlab.ui.control.Label
        Label                   matlab.ui.control.Label
        ConfidenceLabel         matlab.ui.control.Label
        TIMELabel               matlab.ui.control.Label
        Panel1                  matlab.ui.container.Panel
        FromPCButton            matlab.ui.control.Button
        LoadURLButton           matlab.ui.control.Button
        UrlField                matlab.ui.control.EditField
        FromURLEditFieldLabel   matlab.ui.control.Label
        PickImageDropDown       matlab.ui.control.DropDown
        PickImageDropDownLabel  matlab.ui.control.Label
        UIAxes2                 matlab.ui.control.UIAxes
        UIAxes3                 matlab.ui.control.UIAxes
        UIAxes4                 matlab.ui.control.UIAxes
        UIAxes1                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        current_image; %
        current_time;
        
        % Modes of loading pictures // URL // LOCAL STORAGE 
        MODE_URL = 0;
        MODE_LOCAL = 1;
        
    end
   
    methods(Static) 
        function  includePath()
            addpath("../lib/");
            addpath("../images");
            addpath("../images/tempelates");
        end
    end
    
    methods (Access = private)
        
        
        function setUiAxes(app)
            axs = [app.UIAxes1 app.UIAxes2 app.UIAxes3 app.UIAxes4];
            titles = ["Original Image" "Masked Image" "Segmented Image" "Final"];
            
            for i=1:4
                axs(i).XTick = [];
                axs(i).YTick = [];
                xlabel(axs(i), '');
                ylabel(axs(i), '');
                title(axs(i), titles(i));
            end
        end       

        function setDropDownDialog(app)
            options = strings(1,10);
            handels = strings(1,10);
            for i=1:10
                options(i) = "im" + num2str(i);
                handels(i) = "im" + num2str(i) + ".png";
            end
            app.PickImageDropDown.Items = options;
            app.PickImageDropDown.ItemsData = handels;
        end


        function update_gui(app)
            imshow(app.current_image,'Parent',app.UIAxes1);
            img = filter_bright(app.current_image);
            imshow(img,'Parent',app.UIAxes2);
            img = compartment(img);
            imshow(img,'Parent',app.UIAxes3)
            img = isolate_digits(img);
            img = deskew_image(img);
            final = snip(img);
            imshow(final,'Parent',app.UIAxes4);
            [T,C] = guess_time(final);
            app.current_time = mat2str(T);
            app.Label.Text = app.current_time;
            app.Label_2.Text = num2str(C);
        end


        function updateImage(app,mode)
            if mode == app.MODE_LOCAL
                app.current_image = read_image(app.PickImageDropDown.Value);
            elseif mode == app.MODE_URL
                app.current_image = imread(app.UrlField.Value);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.includePath();
            app.setDropDownDialog();
            app.setUiAxes();
            app.updateImage(app.MODE_LOCAL);
            app.update_gui();
        end

        % Callback function
        function DropDownValueChanged(app, event)

        end

        % Value changed function: PickImageDropDown
        function PickImageDropDownValueChanged2(app, event)
            app.updateImage(app.MODE_LOCAL)
            app.update_gui();
            
        end

        % Button pushed function: LoadURLButton
        function LoadURLButtonPushed(app, event)
            app.updateImage(app.MODE_URL);
            app.update_gui();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout1 = uigridlayout(app.UIFigure);
            app.UIAxes1 = uiaxes(app.GridLayout1);
            title(app.UIAxes1, 'Title')
            xlabel(app.UIAxes1, 'X')
            ylabel(app.UIAxes1, 'Y')
            zlabel(app.UIAxes1, 'Z')
            app.UIAxes4 = uiaxes(app.GridLayout1);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes3 = uiaxes(app.GridLayout1);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout1);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.Panel1 = uipanel(app.GridLayout1);
            app.Panel1.Title = 'Panel';
            app.PickImageDropDownLabel = uilabel(app.Panel1);
            app.PickImageDropDownLabel.Text = 'Pick Image';
            app.PickImageDropDown = uidropdown(app.Panel1);
            app.PickImageDropDown.ValueChangedFcn = createCallbackFcn(app, @PickImageDropDownValueChanged2, true);
            app.FromURLEditFieldLabel = uilabel(app.Panel1);
            app.FromURLEditFieldLabel.Text = 'From URL';
            app.UrlField = uieditfield(app.Panel1, 'text');
            app.LoadURLButton = uibutton(app.Panel1, 'push');
            app.LoadURLButton.ButtonPushedFcn = createCallbackFcn(app, @LoadURLButtonPushed, true);
            app.LoadURLButton.Text = 'Load URL';
            app.FromPCButton = uibutton(app.Panel1, 'push');
            app.FromPCButton.Text = 'From PC';
            app.Panel2 = uipanel(app.GridLayout1);
            app.Panel2.Title = 'Panel2';
            app.GridLayout3 = uigridlayout(app.Panel2);
            app.TIMELabel = uilabel(app.GridLayout3);
            app.TIMELabel.Text = 'TIME';
            app.ConfidenceLabel = uilabel(app.GridLayout3);
            app.ConfidenceLabel.Text = 'Confidence';
            app.Label = uilabel(app.GridLayout3);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.GridLayout3);
            app.Label_2.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end